classdef Runner_Szenarien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UQualitySzenarienRunnerGUIUIFigure  matlab.ui.Figure
        ErgebnissePanel                matlab.ui.container.Panel
        ErgebnisstructsLabel           matlab.ui.control.Label
        Ergebnisstructs                matlab.ui.control.ListBox
        ErgebnisseausgebenButton       matlab.ui.control.Button
        ListBoxLabel                   matlab.ui.control.Label
        Szenarienliste                 matlab.ui.control.ListBox
        Label_4                        matlab.ui.control.Label
        SimulationPanel                matlab.ui.container.Panel
        SzenarieneinlesenButton        matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SzenarienberechnenButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Label_5                        matlab.ui.control.Label
        BerechnealleSzenarienCheckBox  matlab.ui.control.CheckBox
        Label_7                        matlab.ui.control.Label
        VollstaendigeErgebnisausgabeCheckBox  matlab.ui.control.CheckBox
        NetzmodellPanel                matlab.ui.container.Panel
        SzenarioIDSpinnerLabel         matlab.ui.control.Label
        SzenarioIDSpinner              matlab.ui.control.Spinner
        NetzmodellffnenButton          matlab.ui.control.Button
        NetzmodellschlieenButton       matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        LoggingPanel                   matlab.ui.container.Panel
        Info                           matlab.ui.control.TextArea
        AktuelleMeldungLabel           matlab.ui.control.Label
        AktuelleMeldungText            matlab.ui.control.Label
    end

    
    properties (Access = private)
        ergebnisstructs_items = {'alle'};% Description
%         timeseries_items = {'phi','pllomega','udgeg','udgegun','udmit','udmitun','uqgeg','uqgegun','uqmit','uqmitun'};
        szenarien_items ={'alle'};
        ergebnisstructs
        timeseries
        Szenarien
        Szenarien_berechnen
        EinzelSzenario
        SzenarioFuerNetz
        Netzmodell_oeffnen
        Szenarien_eingelesen = 0;
        aktZpkt
        scopes
    end
    
    methods (Access = public)
        
        function app = setListBoxItemsErg(app)
            app.Ergebnisstructs.Items = app.ergebnisstructs_items;
        end
        function app = setListBoxItemsSzen(app)
            app.Szenarienliste.Items = app.szenarien_items;
        end
        function app = lese_scopes(app,datei)
            app.scopes = readtable(datei,'Format','auto');            
        end
        function scopes = get_Scopes(app)
            scopes = app.scopes;
        end
        function app = set_ergebnisstructs_items(app)
            for it = 1:size(app.scopes,1)
                if app.scopes{it,'aktiv'} == 1
                    app.ergebnisstructs_items = [app.ergebnisstructs_items{:} app.scopes{it,'Ergebnisstructs'}];
                end
            end
        end
        
        function app = setErgebnisstructs(app,varargin)
%             load workspace_Erg.mat;
%             app.ergebnisstructs =  struct('alle','alle','A_Ortsnetzstation',A_Ortsnetzstation,'A_Ortsnetzstation_RMS',A_Ortsnetzstation_RMS,...
%                                     'B_UPQC',B_UPQC,'B_UPQC_RMS',B_UPQC_RMS,'C_Last',C_Last,'C_Last_RMS',C_Last_RMS,'D_Shunt_UPQC',D_Shunt_UPQC,...
%                                     'D_Shunt_UPQC_RMS',D_Shunt_UPQC_RMS,'E_Series_UPQC',E_Series_UPQC,'E_Series_UPQC_RMS',E_Series_UPQC_RMS);
%             app.ergebnisstructs =  struct('alle','alle',varargin{1},varargin{26},varargin{2},varargin{27},varargin{3},varargin{28},...
%                                     varargin{4},varargin{29},varargin{5},varargin{30},varargin{6},varargin{31},varargin{7},varargin{32},...
%                                     varargin{8},varargin{33},varargin{9},varargin{34},varargin{10},varargin{35},varargin{11},varargin{36},...
%                                     varargin{12},varargin{37},varargin{13},varargin{38},varargin{14},varargin{39},...                                    
%                                     varargin{15},varargin{40},varargin{16},varargin{41},varargin{17},varargin{42},varargin{18},varargin{43},...
%                                     varargin{19},varargin{44},varargin{20},varargin{45},varargin{21},varargin{46},varargin{22},varargin{47},...
%                                     varargin{23},varargin{48},varargin{24},varargin{49},varargin{25},varargin{50});
            app.ergebnisstructs =  struct('alle','alle');
            for it = 1:size(varargin,2)/2
                    app.ergebnisstructs.(varargin{it})= varargin{it+size(varargin,2)/2};
            end
        end
%         function app = setTimeseriesstructs(app)
%             load workspace_Time.mat;
%             app.timeseries = struct('phi',phi,'pllomega',pllomega,'udgeg',udgeg,'udgegun',udgegun,'udmit',udmit,'udmitun',...
%                                udmitun,'uqgeg',uqgeg,'uqgegun',uqgegun,'uqmit',uqmit,'uqmitun',uqmitun);
%         end
        
        function [szenarien, status] = szenarien_einlesen(app,datei,sheet)

            try
                szenarien_input = readtable(datei, 'Sheet', sheet,'Format','auto');
                szenarien_input(szenarien_input.Flag==0,:)=[]; %löschen von Szenarien nicht zu betrachtenden Szenarien
                AnzSzen = size(szenarien_input,1);
                %Initialisierung Szenarien
                for i = 1:AnzSzen
                    szenarien.(['Szen' num2str(i)]) = [];
                end
                ok = true; % Flag zur Prüfung auf NaN in Szenarien.Properties
                
                for i = 1:AnzSzen
                    SzenData.Tsample = str2double(szenarien_input{i,'Tsample'});
                    SzenData.Tsim = str2double(szenarien_input{i,'Tsim'}); 
                    
                    SzenData.tact = strInput2double(szenarien_input{i,'tact'},';');
                    SzenData.tact_Amp = strInput2double(szenarien_input{i,'tact_Amp'},';');
                    SzenData.tacts = strInput2double(szenarien_input{i,'tacts'},';');
                    SzenData.tacts_Amp = strInput2double(szenarien_input{i,'tacts_Amp'},';');
                    SzenData.tact_OS_Regler = strInput2double(szenarien_input{i,'tact_OS_Regler'},';');
                    SzenData.OS_Regler = strInput2double(szenarien_input{i,'OS_Regler'},';');
                    SzenData.QU_possibleON_OFF = strInput2double(szenarien_input{i,'QU_possibleON_OFF'},';');
                    
                    SzenData.Pl_Uns = strInput2double(szenarien_input{i,'Pl_Uns'},';');
                    SzenData.Ql_ind_Uns = strInput2double(szenarien_input{i,'Ql_ind_Uns'},';');
                    SzenData.Ql_kap_Uns = strInput2double(szenarien_input{i,'Ql_kap_Uns'},';');
                    
                    SzenData.Uns_L1_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'Uns_L1_on_off_Zeitpunkte'},',');
                    SzenData.Uns_L2_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'Uns_L2_on_off_Zeitpunkte'},',');
                    SzenData.Uns_L3_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'Uns_L3_on_off_Zeitpunkte'},',');
                    
                    SzenData.RVC_L1_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'RVC_L1_on_off_Zeitpunkte'},',');
                    SzenData.RVC_L2_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'RVC_L2_on_off_Zeitpunkte'},',');
                    SzenData.RVC_L3_on_off_Zeitpunkte = strInput2double(szenarien_input{i,'RVC_L3_on_off_Zeitpunkte'},',');
                    
                    SzenData.Pl_RVC = strInput2double(szenarien_input{i,'Pl_RVC'},';');
                    SzenData.Ql_ind_RVC = strInput2double(szenarien_input{i,'Ql_ind_RVC'},';');                % Blindleistung L1, L2, L3 [Var]
                    SzenData.Ql_kap_RVC = strInput2double(szenarien_input{i,'Ql_kap_RVC'},';');
            
                    SzenData.fak_RVC = strInput2double(szenarien_input{i,'fak_RVC'},','); 
                    SzenData.I_Basis_Ober = strInput2double(szenarien_input{i,'I_Basis_Ober'},';');
                    
                    SzenData.I_relativ_Ober_L1 = strInput2double(szenarien_input{i,'I_relativ_Ober_L1'},' ');    % relativer Anteil von Basisstrom L1 [%] weitere Erklärung siehe S.
                    SzenData.I_relativ_Ober_L2 = strInput2double(szenarien_input{i,'I_relativ_Ober_L2'},' ');    % relativer Anteil von Basisstrom L2 [%] weitere Erklärung siehe S.
                    SzenData.I_relativ_Ober_L3 = strInput2double(szenarien_input{i,'I_relativ_Ober_L3'},' ');    % relativer Anteil von Basisstrom L3 [%] weitere Erklärung siehe S.
            
                    SzenData.Vielfache_Ober_L1 = strInput2double(szenarien_input{i,'Vielfache_Ober_L1'},' '); %Vielfache auf 50Hz normiert L1[] weitere Erklärung siehe S.
                    SzenData.Vielfache_Ober_L2 = strInput2double(szenarien_input{i,'Vielfache_Ober_L2'},' '); %Vielfache auf 50Hz normiert L2[] weitere Erklärung siehe S.
                    SzenData.Vielfache_Ober_L3 = strInput2double(szenarien_input{i,'Vielfache_Ober_L3'},' '); %Vielfache auf 50Hz normiert L3[] weitere Erklärung siehe S.
            
                    SzenData.Phi_Ober = strInput2double(szenarien_input{i,'Phi_Ober'},' ');
                    
                    SzenData.Pl_Flicker = strInput2double(szenarien_input{i,'Pl_Flicker'},';');
                    SzenData.Ql_ind_Flicker = strInput2double(szenarien_input{i,'Ql_ind_Flicker'},';');                % Blindleistung L1, L2, L3 [kVar]
                    SzenData.Ql_kap_Flicker = strInput2double(szenarien_input{i,'Ql_kap_Flicker'},';');
                    SzenData.Flicker_Frequenz = strInput2double(szenarien_input{i,'Flicker_Frequenz'},';');
                    SzenData.Flicker_Start=strInput2double(szenarien_input{i,'Flicker_Start'},';');
                    SzenData.Flicker_Dauer = strInput2double(szenarien_input{i,'Flicker_Dauer'},';');
                    
                    SzenData.Pl_Grund = strInput2double(szenarien_input{i,'Pl_Grund'},';');
                    SzenData.Ql_ind_Grund = strInput2double(szenarien_input{i,'Ql_ind_Grund'},';');
                    SzenData.Ql_kap_Grund = strInput2double(szenarien_input{i,'Ql_kap_Grund'},';');
                    
                    SzenData.Rtrans = str2double(szenarien_input{i,'Rtrans'});
                    SzenData.Ltrans = str2double(szenarien_input{i,'Ltrans'});
                    SzenData.strans = str2double(szenarien_input{i,'strans'});
                    SzenData.fsw = str2double(szenarien_input{i,'fsw'});
                    SzenData.Czk = str2double(szenarien_input{i,'Czk'});
                    
                    SzenData.L_Eingang_Shunt_L1_L2_L3_N = strInput2double(szenarien_input{i,'L_Eingang_Shunt_L1_L2_L3_N'},';');
                    SzenData.R_Eingang_Shunt_L1_L2_L3_N	= strInput2double(szenarien_input{i,'R_Eingang_Shunt_L1_L2_L3_N'},';');
                    SzenData.L_Ausgang_Shunt_L1_L2_L3_N	= strInput2double(szenarien_input{i,'L_Ausgang_Shunt_L1_L2_L3_N'},';');
                    SzenData.R_Ausgang_Shunt_L1_L2_L3_N	= strInput2double(szenarien_input{i,'R_Ausgang_Shunt_L1_L2_L3_N'},';');
                    SzenData.C_parallel_Shunt_L1_L2_L3 = strInput2double(szenarien_input{i,'C_parallel_Shunt_L1_L2_L3'},';');
                    SzenData.R_parallel_Shunt_L1_L2_L3 = strInput2double(szenarien_input{i,'R_parallel_Shunt_L1_L2_L3'},';');
                    
                    SzenData.L_Eingang_Series_L1_L2_L3_N = strInput2double(szenarien_input{i,'L_Eingang_Series_L1_L2_L3_N'},';');	
                    SzenData.R_Eingang_Series_L1_L2_L3_N = strInput2double(szenarien_input{i,'R_Eingang_Series_L1_L2_L3_N'},';');
                    SzenData.L_Ausgang_Series_L1_L2_L3_N = strInput2double(szenarien_input{i,'L_Ausgang_Series_L1_L2_L3_N'},';');	
                    SzenData.R_Ausgang_Series_L1_L2_L3_N = strInput2double(szenarien_input{i,'R_Ausgang_Series_L1_L2_L3_N'},';');	
                    SzenData.C_parallel_Series_L1_L2_L3 = strInput2double(szenarien_input{i,'C_parallel_Series_L1_L2_L3'},';');
                    SzenData.R_parallel_Series_L1_L2_L3 = strInput2double(szenarien_input{i,'R_parallel_Series_L1_L2_L3'},';');
                    
%                     SzenData.trafo_power = str2double(szenarien_input{i,'trafo_power'});
%                     SzenData.phase_voltage_1 = str2double(szenarien_input{i,'phase_voltage_1'});
%                     SzenData.R_pu_1 = str2double(szenarien_input{i,'R_pu_1'});
%                     SzenData.X_pu_1 = str2double(szenarien_input{i,'X_pu_1'});
%                     SzenData.phase_voltage_2 = str2double(szenarien_input{i,'phase_voltage_2'});
%                     SzenData.R_pu_2 = str2double(szenarien_input{i,'R_pu_2'});
%                     SzenData.X_pu_2 = str2double(szenarien_input{i,'X_pu_2'});
%                     SzenData.L1_limbs = str2double(szenarien_input{i,'L1_limbs'});
%                     SzenData.A1_limbs = str2double(szenarien_input{i,'A1_limbs'});
%                     SzenData.L2_yokes = str2double(szenarien_input{i,'L2_yokes'});
%                     SzenData.A2_yokes = str2double(szenarien_input{i,'A2_yokes'});
%                     SzenData.L0_flux = str2double(szenarien_input{i,'L0_flux'});
%                     SzenData.A0_flux = str2double(szenarien_input{i,'A0_flux'});
%                     SzenData.winding1 = str2double(szenarien_input{i,'winding1'});
                    
%                     SzenData.BH = strInput2double(szenarien_input{i,'BH'},';','BH');
%                     SzenData.active_power_losses_trafo = str2double(szenarien_input{i,'active_power_losses_trafo'});
%                     SzenData.flux_initialization = strInput2double(szenarien_input{i,'flux_initialization'},' ');
%                     SzenData.trafo_time_constant = str2double(szenarien_input{i,'trafo_time_constant'});
                    
                    SzenData.Speicherpfad = cell2mat(szenarien_input{i,'Speicherpfad'});
                    SzenData.User_ID = cell2mat(szenarien_input{i,'User_ID'});
                    SzenData.Szenario_ID = szenarien_input{i,'Szenario_ID'};
            
                    akt_Szenario = Szenario(SzenData);
                    akt_Szenario = checkPropertiesForNaN(akt_Szenario);
                    akt_Szenario = errordetectionOnProperties(akt_Szenario);
                    if akt_Szenario.Status == 0
                        ok = false;                        
                        if ~isempty(akt_Szenario.Fehlerliste)                            
                            app.Info.Value{end+1} = ['Fehler beim Szenarien (Szenario ' num2str(akt_Szenario.Szenario_ID) ') einlesen! Einlesen wird beendet.'];
                            app.AktuelleMeldungText.Text = 'Fehler beim Szenarien einlesen! Einlesen wird beendet.';
                            for ierr = 1:size(akt_Szenario.Fehlerliste)
                                app.Info.Value{end+1} = akt_Szenario.Fehlerliste{ierr};
                                app.AktuelleMeldungText.Text = akt_Szenario.Fehlerliste{ierr};       
                                disp(akt_Szenario.Fehlerliste{ierr});
                            end
                            scroll(app.Info, 'bottom');  
                            pause(0.00000001);
                            error('Fehler beim Szenarien einlesen! Einlesen wird beendet.')
                        end
                    end
                    szenarien.(['Szen' num2str(i)]) = akt_Szenario;
                    if app.Szenarien_eingelesen && i == 1
                        app.szenarien_items = {'alle'};
                        app.szenarien_items = [app.szenarien_items, {['Szen' num2str(i)]}];
                    else
                        app.szenarien_items = [app.szenarien_items, {['Szen' num2str(i)]}];
                    end
                end
                app = setListBoxItemsSzen(app);
                ID = (1:size(szenarien_input,1))';
                tID = table(ID);
                if app.BerechnealleSzenarienCheckBox.Value == true
                    Berechnen = true(size(szenarien_input,1),1);
                else
                    Berechnen = false(size(szenarien_input,1),1);
                end
                tBerechnen = table(Berechnen);
                app.UITable.Data = [tID szenarien_input(:,{'Szenario_ID','User_ID','Speicherpfad'}) tBerechnen];
                if ok
                    status = 1;
                else
                    status = 0; 
                end
                app.Szenarien_eingelesen = 1;
                if AnzSzen > 4
                    app.UITable.ColumnWidth = {59,82,82,105,78};
                else
                    app.UITable.ColumnWidth = {60,85,85,105,88};
                end
            catch err
                disp(err.message);
                status = -1;
                szenarien = [];
            end
            scroll(app.Info, 'bottom');
            pause(0.00000001);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % relevante Ordner zum Pfad hinzufügen (U-Quality)
            oldFolder = cd('../..'); % Zwei Ebenen hoch springen
            addpath(genpath(pwd)); % aktuellen Ordner und alle Unterordner zum Pfad hinzufügen
            cd(oldFolder); % wieder zurück springen

            app.Netzmodell_oeffnen = false;
            app.lese_scopes('Scopes.csv');
            app.set_ergebnisstructs_items();
            app = setListBoxItemsErg(app);
            app.SzenarioIDSpinner.Limits = [0,1];
            value = app.SzenarioIDSpinner.Value;
            app.SzenarioFuerNetz = value;
            app.Info.Value{1} = 'Willkommen bei der Szenarien-Runner-GUI von U-Quality';
            
        end

        % Button pushed function: ErgebnisseausgebenButton
        function ErgebnisseausgebenButtonPushed(app, event)
            auswahlSzen = app.Szenarienliste.Value;
            if isempty(auswahlSzen)
                fig = uifigure;
                [~] = uiconfirm(fig,'Es wurden keine Szenarien zur Ausgabe ausgewählt. Bitte zunächst auszugebende Szenarien auswählen.',...
                    'WARNUNG: Keine Szenarien ausgewählt','Icon','warning');
                close(fig);
                
                app.Info.Value{end+1} = 'Es wurden keine Szenarien ausgewählt. Abbruch der Ergebnisausgabe.';
                app.AktuelleMeldungText.Text = 'Es wurden keine Szenarien ausgewählt. Abbruch der Ergebnisausgabe.';
                scroll(app.Info, 'bottom');
                pause(0.00000001);
                return;
            end
            ausgabeSzen_name = string(auswahlSzen);
            ausgabe_struct = struct;
            if ausgabeSzen_name(1) == "alle"
                for j=2:size(app.szenarien_items,2)
                    auswahl = app.Ergebnisstructs.Value;
                    ausgabe_name = string(auswahl);
                    
                    SzenErg = app.Szenarien.(char(app.szenarien_items(j))).Ergebnisse;
                    SzenWSIni = app.Szenarien.(char(app.szenarien_items(j))).Workspace_Ini;
                    if isempty(SzenErg)
                        fig = uifigure;
                        selection = uiconfirm(fig,'Es wurden noch keine Szenarien berechnet, so dass Ergebnisse ausgegeben werden könnten. Szenarien jetzt berechnen?',...
                            'WARNUNG: Keine Szenarien berechnet','Icon','warning');
                        close(fig);
                        if strcmp(selection,'OK')
                            berechne_Szenarien(app,event);
                            SzenErg = app.Szenarien.(char(app.szenarien_items(j))).Ergebnisse;
                        else
                            app.Info.Value{end+1} = 'Es wurden keine Szenarien berechnet. Abbruch der Ergebnisausgabe.';
                            app.AktuelleMeldungText.Text = 'Es wurden keine Szenarien berechnet. Abbruch der Ergebnisausgabe.';
                            scroll(app.Info, 'bottom');
                            pause(0.00000001);
                            return;
                        end
                    end
                    
                    ergvars = cell(1,size(app.ergebnisstructs_items,2)-1);
                    for itergi = 2:size(app.ergebnisstructs_items,2)
                        ergvars{itergi-1} = SzenErg.(app.ergebnisstructs_items{itergi});
                    end
                    % Speichere Ergebnisse in Szenario Ergebnisse
                    app = setErgebnisstructs(app,app.ergebnisstructs_items{2:end},ergvars{:});
                    
                    if ausgabe_name(1) == "alle"
                        for i = 2:size(app.ergebnisstructs_items,2)
                            ausgabe_wert = app.ergebnisstructs.(char(app.ergebnisstructs_items(i)));
                            ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(app.szenarien_items(j))).Szenario_ID))]).(char(app.ergebnisstructs_items(i))) = ausgabe_wert;
        %                     save(string(app.ergebnisstructs_items(i)),'ausgabe_wert','-v7.3');
                        end
%                         save('Ergebnisse','ausgabe_struct','-v7.3');
                    else            
                        for i = 1:size(ausgabe_name,2)
                            ausgabe_wert = app.ergebnisstructs.(char(ausgabe_name(i)));
                            ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(app.szenarien_items(j))).Szenario_ID))]).(char(ausgabe_name(i))) = ausgabe_wert;
        %                     save(ausgabe_name(i),'ausgabe_wert','-v7.3');
                        end
%                         save('Ergebnisse','ausgabe_struct','-v7.3');
                    end
                    ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(app.szenarien_items(j))).Szenario_ID))]).Workspace_Ini = SzenWSIni;
                end
            else
                for j=1:size(ausgabeSzen_name,2)
                    auswahl = app.Ergebnisstructs.Value;
                    ausgabe_name = string(auswahl);
                    
                    SzenErg = app.Szenarien.(char(ausgabeSzen_name(j))).Ergebnisse;
                    SzenWSIni = app.Szenarien.(char(ausgabeSzen_name(j))).Workspace_Ini;
                    if isempty(SzenErg)
                        fig = uifigure;
                        selection = uiconfirm(fig,'Es wurden noch keine Szenarien berechnet, so dass Ergebnisse ausgegeben werden könnten. Szenarien jetzt berechnen?',...
                            'WARNUNG: Keine Szenarien berechnet','Icon','warning');
                        close(fig);
                        if strcmp(selection,'OK')
                            berechne_Szenarien(app,event);
                            SzenErg = app.Szenarien.(char(ausgabeSzen_name(j))).Ergebnisse;
                        else
                            app.Info.Value{end+1} = 'Es wurden keine Szenarien berechnet. Abbruch der Ergebnisausgabe.';
                            app.AktuelleMeldungText.Text = 'Es wurden keine Szenarien berechnet. Abbruch der Ergebnisausgabe.';
                            scroll(app.Info, 'bottom');
                            pause(0.00000001);
                            return;
                        end
                    end

                    ergvars = cell(1,size(app.ergebnisstructs_items,2)-1);
                    for itergi = 2:size(app.ergebnisstructs_items,2)
                        ergvars{itergi-1} = SzenErg.(app.ergebnisstructs_items{itergi});
                    end
                    % Speichere Ergebnisse in Szenario Ergebnisse
                    app = setErgebnisstructs(app,app.ergebnisstructs_items{2:end},ergvars{:});
                    
                    if ausgabe_name(1) == "alle"
                        for i = 2:size(app.ergebnisstructs_items,2)
                            ausgabe_wert = app.ergebnisstructs.(char(app.ergebnisstructs_items(i)));
                            ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(ausgabeSzen_name(j))).Szenario_ID))]).(char(app.ergebnisstructs_items(i))) = ausgabe_wert;
        %                     save(string(app.ergebnisstructs_items(i)),'ausgabe_wert','-v7.3');
                        end
%                         save('Ergebnisse','ausgabe_struct','-v7.3');
                    else            
                        for i = 1:size(ausgabe_name,2)
                            ausgabe_wert = app.ergebnisstructs.(char(ausgabe_name(i)));
                            ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(ausgabeSzen_name(j))).Szenario_ID))]).(char(ausgabe_name(i))) = ausgabe_wert;
%                             ausgabe_struct.(char(ausgabeSzen_name(j))).(char(app.ergebnisstructs_items(i+1))) = ausgabe_wert;
        %                     save(ausgabe_name(i),'ausgabe_wert','-v7.3');
                        end
%                         save('Ergebnisse','ausgabe_struct','-v7.3');
                    end
                    ausgabe_struct.(['Szen_' char(num2str(app.Szenarien.(char(ausgabeSzen_name(j))).Szenario_ID))]).Workspace_Ini = SzenWSIni;
                end
            end
            

            sZeitstempel = datestr(now,'yyyy_mm_dd_HHMMSS');
            ausgabe_struct.Berechnungsdatum = sZeitstempel;            
            DatnameAusgabe=[sZeitstempel,'.mat'];
            outfields = fields(ausgabe_struct);
            Speicherpfad = 'hier';
            berechneteSzenarien = fields(app.Szenarien);
            for i = 1:size(berechneteSzenarien,1)
                if strcmp(['Szen_' num2str(app.Szenarien.(berechneteSzenarien{i}).Szenario_ID)],outfields{1})
                    Speicherpfad = app.Szenarien.(berechneteSzenarien{i}).Speicherpfad;
                    break;
                end
            end
%             save([app.Szenarien.(outfields{1}).Speicherpfad,'\',DatnameAusgabe],'ausgabe_struct','-v7.3');
            save([Speicherpfad,'\',DatnameAusgabe],'ausgabe_struct','-v7.3');
            
            disp('Ausgabe fertig');
            app.Info.Value{end+1} = 'Ausgabe fertig';
            app.AktuelleMeldungText.Text = 'Ausgabe fertig';
            scroll(app.Info, 'bottom');
            pause(0.00000001);
        end

        % Button pushed function: SzenarieneinlesenButton
        function starte_szenarien_einlesen(app, event)
            %Szenarien aus Excel-Liste einlesen
            app.Info.Value{end+1} = 'Lese Szenarien ein ...';
            app.AktuelleMeldungText.Text = 'Die Szenarien werden jetzt eingelesen ... ;-)';            
            scroll(app.Info, 'bottom');
            pause(0.00000001);
            [szenarien, status] = szenarien_einlesen(app,'Szenarienliste.xlsx','Szenarien');
            if status == -1
                disp('Fehler beim Szenarien einlesen! Einlesen wird beendet.')
                app.Info.Value{end+1} = 'Fehler beim Szenarien einlesen! Einlesen wird beendet.';
                app.AktuelleMeldungText.Text = 'Fehler beim Szenarien einlesen! Einlesen wird beendet.';
                return;
            elseif status == 0
                disp('Szenarien unvollständig. Einzelne Funktionen des Netzmodells ggf. nicht nutzbar.');
                app.Info.Value{end+1} = 'Szenarien unvollständig. Einzelne Funktionen des Netzmodells ggf. nicht nutzbar.';
                app.AktuelleMeldungText.Text = 'Szenarien unvollständig. Einzelne Funktionen des Netzmodells ggf. nicht nutzbar.';
            else
                disp('Szenarien Einlesen erfolgreich.');
                app.Info.Value{end+1} = 'Szenarien Einlesen erfolgreich.';
                app.AktuelleMeldungText.Text = 'Szenarien Einlesen erfolgreich.';
            end
            app.Szenarien = szenarien;
            if size(fields(app.Szenarien),1)>1
                app.SzenarioIDSpinner.Limits = [1,size(fields(app.Szenarien),1)];
            else
                app.SzenarioIDSpinner.Limits = [0,1];
            end
            disp('Szenarien einlesen fertig');
            app.Info.Value{end+1} = 'Szenarien einlesen fertig';
            app.AktuelleMeldungText.Text = 'Szenarien einlesen fertig';            
            scroll(app.Info, 'bottom');
            pause(0.00000001);
        end

        % Button pushed function: SzenarienberechnenButton
        function berechne_Szenarien(app, event)
            app.aktZpkt = datestr(now,'yyyymmdd_HHMM'); %Startzeitpunkt Szenarienberechnung fuer Ergebnisausgabe
            if ~app.Szenarien_eingelesen
                fig = uifigure;
                selection = uiconfirm(fig,'Es wurden noch keine Szenarien eingelesen, die berechnet werden könnten. Szenarien jetzt einlesen?',...
                    'WARNUNG: Keine Szenarien eingelesen','Icon','warning');
                close(fig);
                if strcmp(selection,'OK')
                    starte_szenarien_einlesen(app,event);
                end
            end
            
            if ~app.Szenarien_eingelesen
                disp('Es wurden keine Szenarien berechnet!');
                app.Info.Value{end+1} = 'Es wurden keine Szenarien berechnet!';
                app.AktuelleMeldungText.Text = 'Es wurden keine Szenarien berechnet!';
            else
                fig = uifigure;
                d = uiprogressdlg(fig,'Title','Bitte warten...',...
                        'Message','Öffnen der Anwendung.');
                app.Szenarien_berechnen = app.Szenarien;
                for itdelF = 1:size(app.UITable.Data.Berechnen,1)
                    if app.UITable.Data.Berechnen(itdelF)==0
                        app.Szenarien_berechnen = rmfield(app.Szenarien_berechnen,['Szen' num2str(itdelF)]);
                    end
                end
                berechne_fields = fields(app.Szenarien_berechnen);
                for iterSzen = 1:size(berechne_fields,1)
                % for iterSzen = 8:size(fields(szenarien),1)
                    berechne_field = berechne_fields{iterSzen};
                    disp(['Start von Szenario-Durchlauf: ',berechne_field])
                    app.Info.Value{end+1} = ['Simuliere Szenario: ',berechne_field];
                    app.AktuelleMeldungText.Text = ['Simuliere Szenario: ',berechne_field];
                    d.Value = iterSzen/size(fields(app.Szenarien_berechnen),1); 
                    d.Message = ['Simuliere Szenario: ',berechne_field];
                    Szenario = app.Szenarien_berechnen.(berechne_field);
                    if ~exist(Szenario.Speicherpfad,'dir')
                       mkdir(Szenario.Speicherpfad);
                    end
                    Netzmodell_oeffnen = app.Netzmodell_oeffnen;
                    run("Netzmodell_Ini_DL.m");
                    
                    S = load('workspace_Ini.mat');
                    fieldsS = fieldnames(S);
                    for iterS = 1:size(fieldsS,1)
                        assignin("base",fieldsS{iterS},S.(fieldsS{iterS}));
                    end

                    sim('UPQC_model');
                    berechne_fields = fields(app.Szenarien_berechnen);
                    berechne_field = berechne_fields{iterSzen};
                    app.Info.Value{end+1} = ['Szenario: ',berechne_field,' simuliert. Speichere Ergebnisse.'];
                    app.AktuelleMeldungText.Text = ['Szenario: ',berechne_field,' simuliert. Speichere Ergebnisse.'];
                    if app.VollstaendigeErgebnisausgabeCheckBox.Value == true
                        if ~exist([app.Szenarien_berechnen.('Szen1').Speicherpfad '\' app.aktZpkt],'dir')
                            mkdir([app.Szenarien_berechnen.('Szen1').Speicherpfad '\' app.aktZpkt]);
                        end
                        save([app.Szenarien_berechnen.('Szen1').Speicherpfad '\' app.aktZpkt '\workspace_Erg' berechne_field '_ID' char(num2str(app.Szenarien.(char(app.szenarien_items(iterSzen+1))).Szenario_ID)) '.mat'],app.ergebnisstructs_items{2:end});
%                         save([app.Szenarien_berechnen.('Szen1').Speicherpfad '\' app.aktZpkt '\workspace_Time' berechne_field '.mat'],app.timeseries_items{:});
                    else
                        save('workspace_Erg.mat',app.ergebnisstructs_items{2:end});
%                         save('workspace_Time.mat',app.timeseries_items{:});

                        ergvars = cell(1,size(app.ergebnisstructs_items,2)-1);
                        for itergi = 2:size(app.ergebnisstructs_items,2)
                            ergvars{itergi-1} = eval(app.ergebnisstructs_items{itergi});
                        end
                        % Speichere Ergebnisse in Szenario Ergebnisse
                        app = setErgebnisstructs(app,app.ergebnisstructs_items{2:end},ergvars{:});
                        Ergebnisse = app.ergebnisstructs;
                        Ergebnisse = rmfield(Ergebnisse,'alle');
                        Szenario = Szenario.hole_Ergebnisse(Ergebnisse);
                        Szenario = Szenario.set_Workspace_Ini(S);
                        app.Szenarien.(berechne_field) = Szenario;
                    end
                end
                
                app.szenarien_items = [{'alle'}, berechne_fields'];
                app = setListBoxItemsSzen(app);
                % Close dialog box
                close(d);
                close(fig);
                disp('Szenarien berechnen fertig');
                app.Info.Value{end+1} = 'Szenarien berechnen fertig'; 
                app.AktuelleMeldungText.Text = 'Szenarien berechnen fertig';
            end
            
            scroll(app.Info, 'bottom');
            pause(0.00000001);
        end

        % Button pushed function: NetzmodellffnenButton
        function oeffne_Netzmodell(app, event)
            if ~isempty(app.Szenarien)
                Szenario = app.Szenarien.(['Szen' num2str(app.SzenarioFuerNetz)]);
                app.Netzmodell_oeffnen = true;
                Netzmodell_oeffnen = app.Netzmodell_oeffnen;
                run("Netzmodell_Ini_DL.m");
                S = load('workspace_Ini.mat');
                fieldsS = fieldnames(S);
                for iterS = 1:size(fieldsS,1)
                    assignin("base",fieldsS{iterS},S.(fieldsS{iterS}));
                end
                app.Netzmodell_oeffnen = false;
                disp(['Netzmodell mit Szenario ' num2str(Szenario.Szenario_ID) ' geladen']);
                app.Info.Value{end+1} = ['Netzmodell mit Szenario ' num2str(Szenario.Szenario_ID) ' geladen'];
                app.AktuelleMeldungText.Text = ['Netzmodell mit Szenario ' num2str(Szenario.Szenario_ID) ' geladen'];
            else
                disp('Es sind keine Szenarien geladen. Bitte zunächst Szenarien laden!');
                app.Info.Value{end+1} = 'Es sind keine Szenarien geladen. Bitte zunächst Szenarien laden!';
                app.AktuelleMeldungText.Text = 'Es sind keine Szenarien geladen. Bitte zunächst Szenarien laden!';
            end
            
            scroll(app.Info, 'bottom');
            pause(0.00000001);
        end

        % Value changed function: SzenarioIDSpinner
        function setze_Szenario_fuer_Netzmodell(app, event)
            value = app.SzenarioIDSpinner.Value;
            app.SzenarioFuerNetz = value;
        end

        % Button pushed function: NetzmodellschlieenButton
        function netzmodell_schliessen(app, event)
            speichern = questdlg("Soll das Netzmodell gespeichert werden?");
            if strcmp(speichern,'Yes')
                close_system('UPQC_model',1);
            elseif strcmp(speichern,'No')
                close_system('UPQC_model',0);
            end
        end

        % Value changed function: Szenarienliste
        function SzenarienlisteValueChanged(app, event)
            value = app.Szenarienliste.Value;
            value = sort(value);
            app.Szenarienliste.Value = value;
        end

        % Value changed function: BerechnealleSzenarienCheckBox
        function berechne_alle_Szenarien(app, event)
            value = app.BerechnealleSzenarienCheckBox.Value;
            if value
                app.VollstaendigeErgebnisausgabeCheckBox.Value = true;
                app.ErgebnissePanel.Enable = 'off';
            else
                app.VollstaendigeErgebnisausgabeCheckBox.Value = false;
                app.ErgebnissePanel.Enable = 'on';
            end
            if ~isempty(app.UITable.Data)
                if value
                    app.UITable.Data.Berechnen = true(size(app.UITable.Data,1),1);                    
                else
                    app.UITable.Data.Berechnen = false(size(app.UITable.Data,1),1);
                end
            end
        end

        % Value changed function: 
        % VollstaendigeErgebnisausgabeCheckBox
        function VollstaendigeErgebnisausgabeCheckBoxValueChanged(app, event)
            value = app.VollstaendigeErgebnisausgabeCheckBox.Value;
            if value
                app.ErgebnissePanel.Enable = 'off';
            else
                app.ErgebnissePanel.Enable = 'on';
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UQualitySzenarienRunnerGUIUIFigure = uifigure('Visible', 'off');
            app.UQualitySzenarienRunnerGUIUIFigure.Name = 'U-Quality Szenarien Runner GUI';
            app.ErgebnissePanel = uipanel(app.UQualitySzenarienRunnerGUIUIFigure);
            app.ErgebnissePanel.Title = 'Ergebnisse';
            app.ErgebnisstructsLabel = uilabel(app.ErgebnissePanel);
            app.ErgebnisstructsLabel.Text = {'Auswahl Ergebnisse für Ausgabe. '; 'Mehrfachauswahl möglich (strg+linksklick).'};
            app.Ergebnisstructs = uilistbox(app.ErgebnissePanel);
            app.Ergebnisstructs.Items = {'alle', 'A_Ortsnetzstation', 'A_Ortsnetzstation_RMS', 'B_UPQC', 'B_UPQC_RMS', 'C_Last', 'C_Last_RMS', 'D_Shunt_UPQC', 'D_Shunt_UPQC_RMS', 'E_Series_UPQC', 'E_Series_UPQC_RMS'};
            app.Ergebnisstructs.Multiselect = 'on';
            app.Ergebnisstructs.Value = {'alle'};
            app.ErgebnisseausgebenButton = uibutton(app.ErgebnissePanel, 'push');
            app.ErgebnisseausgebenButton.ButtonPushedFcn = createCallbackFcn(app, @ErgebnisseausgebenButtonPushed, true);
            app.ErgebnisseausgebenButton.Text = 'Ergebnisse ausgeben';
            app.ListBoxLabel = uilabel(app.ErgebnissePanel);
            app.ListBoxLabel.Text = {'Auswahl Szenarien für Ausgabe.'; 'Mehrfachauswahl möglich'; '(strg+linksklick).'};
            app.Szenarienliste = uilistbox(app.ErgebnissePanel);
            app.Szenarienliste.Items = {};
            app.Szenarienliste.Multiselect = 'on';
            app.Szenarienliste.ValueChangedFcn = createCallbackFcn(app, @SzenarienlisteValueChanged, true);
            app.Szenarienliste.Value = {};
            app.Label_4 = uilabel(app.ErgebnissePanel);
            app.Label_4.Text = {'Gibt die ausgewählten Ergebnisstructs '; 'für die ausgewählten Szenarien aus.'};
            app.SimulationPanel = uipanel(app.UQualitySzenarienRunnerGUIUIFigure);
            app.SimulationPanel.Title = 'Simulation';
            app.SzenarieneinlesenButton = uibutton(app.SimulationPanel, 'push');
            app.SzenarieneinlesenButton.ButtonPushedFcn = createCallbackFcn(app, @starte_szenarien_einlesen, true);
            app.SzenarieneinlesenButton.Text = 'Szenarien einlesen';
            app.Label_2 = uilabel(app.SimulationPanel);
            app.Label_2.Text = 'Liest alle Szenarien aus Excelliste mit Flag ''1'' ein.';
            app.Label = uilabel(app.SimulationPanel);
            app.Label.Text = 'Berechnet die ausgewählten Szenarien.';
            app.SzenarienberechnenButton = uibutton(app.SimulationPanel, 'push');
            app.SzenarienberechnenButton.ButtonPushedFcn = createCallbackFcn(app, @berechne_Szenarien, true);
            app.SzenarienberechnenButton.Text = 'Szenarien berechnen';
            app.UITable = uitable(app.SimulationPanel);
            app.UITable.ColumnName = {'ID'; 'Szenario_ID'; 'User_ID'; 'Speicherpfad'; 'Berechnen'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Label_5 = uilabel(app.SimulationPanel);
            app.Label_5.Text = {'Schnellauswahl zur Berechnung '; 'aller Szenarien in der Tabelle'};
            app.BerechnealleSzenarienCheckBox = uicheckbox(app.SimulationPanel);
            app.BerechnealleSzenarienCheckBox.ValueChangedFcn = createCallbackFcn(app, @berechne_alle_Szenarien, true);
            app.BerechnealleSzenarienCheckBox.Text = 'Berechne alle Szenarien';
            app.Label_7 = uilabel(app.SimulationPanel);
            app.Label_7.Text = {'Zur Initialisierung des Netzmodells mit einem'; 'konkreten Szenario bitte "Netzmodell öffnen" nutzen.'};
            app.VollstaendigeErgebnisausgabeCheckBox = uicheckbox(app.SimulationPanel);
            app.VollstaendigeErgebnisausgabeCheckBox.ValueChangedFcn = createCallbackFcn(app, @VollstaendigeErgebnisausgabeCheckBoxValueChanged, true);
            app.VollstaendigeErgebnisausgabeCheckBox.Text = {'Vollständige Ergebnisausgabe für alle berechneten Szenarien.'; '(Funktionalität sinnvoll bei Berechnung sehr vieler Szenarien)'};
            app.NetzmodellPanel = uipanel(app.UQualitySzenarienRunnerGUIUIFigure);
            app.NetzmodellPanel.Title = 'Netzmodell';
            app.SzenarioIDSpinnerLabel = uilabel(app.NetzmodellPanel);
            app.SzenarioIDSpinnerLabel.Text = 'Szenario (ID)';
            app.SzenarioIDSpinner = uispinner(app.NetzmodellPanel);
            app.SzenarioIDSpinner.Limits = [0 1];
            app.SzenarioIDSpinner.ValueChangedFcn = createCallbackFcn(app, @setze_Szenario_fuer_Netzmodell, true);
            app.SzenarioIDSpinner.Value = 1;
            app.NetzmodellffnenButton = uibutton(app.NetzmodellPanel, 'push');
            app.NetzmodellffnenButton.ButtonPushedFcn = createCallbackFcn(app, @oeffne_Netzmodell, true);
            app.NetzmodellffnenButton.Text = 'Netzmodell öffnen';
            app.NetzmodellschlieenButton = uibutton(app.NetzmodellPanel, 'push');
            app.NetzmodellschlieenButton.ButtonPushedFcn = createCallbackFcn(app, @netzmodell_schliessen, true);
            app.NetzmodellschlieenButton.Text = 'Netzmodell schließen';
            app.Label_3 = uilabel(app.NetzmodellPanel);
            app.Label_3.Text = {'Öffnet das Netzmodell mit den Parametern '; 'des ausgewählten Szenarios (ID)'};
            app.LoggingPanel = uipanel(app.UQualitySzenarienRunnerGUIUIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.Info = uitextarea(app.LoggingPanel);
            app.Info.Tag = 'Info: ';
            app.AktuelleMeldungLabel = uilabel(app.LoggingPanel);
            app.AktuelleMeldungLabel.Text = 'Aktuelle Meldung: ';
            app.AktuelleMeldungText = uilabel(app.LoggingPanel);
            app.AktuelleMeldungText.Text = '-';
            % Show the figure after all components are created
            app.UQualitySzenarienRunnerGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runner_Szenarien

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UQualitySzenarienRunnerGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UQualitySzenarienRunnerGUIUIFigure)
        end
    end
end